classdef Transformer_design < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INPUTPANALTab                   matlab.ui.container.Tab
        TRANSFORMERDESIGNUSINGMATLABGUIPanel  matlab.ui.container.Panel
        CalculateButton                 matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        DropDown                        matlab.ui.control.DropDown
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        TransformerCapacityEditFieldLabel  matlab.ui.control.Label
        TransformerCapacityEditField    matlab.ui.control.NumericEditField
        PrimarywindingvoltageEditFieldLabel  matlab.ui.control.Label
        PrimarywindingvoltageEditField  matlab.ui.control.NumericEditField
        SecondarywindingvoltageEditFieldLabel  matlab.ui.control.Label
        SecondarywindingvoltageEditField  matlab.ui.control.NumericEditField
        Panel_2                         matlab.ui.container.Panel
        Amm2Label                       matlab.ui.control.Label
        degreeCelciusLabel              matlab.ui.control.Label
        CurrentDensityEditFieldLabel    matlab.ui.control.Label
        CurrentDensityEditField         matlab.ui.control.NumericEditField
        MaxMagneticfluxdensityEditFieldLabel  matlab.ui.control.Label
        MaxMagneticfluxdensityEditField  matlab.ui.control.NumericEditField
        RatioofwindowheighttowindowwidthinDecimalformEditFieldLabel  matlab.ui.control.Label
        RatioofwindowheighttowindowwidthinDecimalformEditField  matlab.ui.control.NumericEditField
        SelectTheMaximumTemperatureEditFieldLabel  matlab.ui.control.Label
        SelectTheMaximumTemperatureEditField  matlab.ui.control.NumericEditField
        InsulationWidthEditFieldLabel   matlab.ui.control.Label
        InsulationWidthEditField        matlab.ui.control.NumericEditField
        mmLabel_16                      matlab.ui.control.Label
        TappingLabel                    matlab.ui.control.Label
        TappingEditField                matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        pfEditFieldLabel                matlab.ui.control.Label
        pfEditField                     matlab.ui.control.NumericEditField
        Panel_3                         matlab.ui.container.Panel
        TypeDropDownLabel               matlab.ui.control.Label
        TypeDropDown                    matlab.ui.control.DropDown
        frequencyDropDownLabel          matlab.ui.control.Label
        frequencyDropDown               matlab.ui.control.DropDown
        WindingtypeDropDown             matlab.ui.control.DropDown
        CoreMaterialDropDownLabel       matlab.ui.control.Label
        CoreMaterialDropDown            matlab.ui.control.DropDown
        CoretypeDropDownLabel           matlab.ui.control.Label
        CoretypeDropDown                matlab.ui.control.DropDown
        DesignPreferenceDropDownLabel   matlab.ui.control.Label
        DesignPreferenceDropDown        matlab.ui.control.DropDown
        TransformeroiltypeDropDownLabel  matlab.ui.control.Label
        TransformeroiltypeDropDown      matlab.ui.control.DropDown
        WindingTypeLabel                matlab.ui.control.Label
        OutputConstantkEditFieldLabel   matlab.ui.control.Label
        OutputConstantkEditField        matlab.ui.control.NumericEditField
        COMPANYNAMEEditFieldLabel       matlab.ui.control.Label
        COMPANYNAMEEditField            matlab.ui.control.EditField
        EditField                       matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Lamp                            matlab.ui.control.Lamp
        CoreandYokedesignTab            matlab.ui.container.Tab
        COREFRAMEDESIGNOUTPUTPanel      matlab.ui.container.Panel
        SizeoflaminationPanel           matlab.ui.container.Panel
        SmallestWidthbEditFieldLabel    matlab.ui.control.Label
        SmallestWidthbEditField         matlab.ui.control.NumericEditField
        ndLargestWidthEditFieldLabel    matlab.ui.control.Label
        ndLargestWidthEditField         matlab.ui.control.NumericEditField
        rdLargestWidthEditFieldLabel    matlab.ui.control.Label
        rdLargestWidthEditField         matlab.ui.control.NumericEditField
        mmLabel_11                      matlab.ui.control.Label
        mmLabel_12                      matlab.ui.control.Label
        mmLabel_13                      matlab.ui.control.Label
        mmLabel_14                      matlab.ui.control.Label
        LargestStampingWidthaEditFieldLabel  matlab.ui.control.Label
        LargestStampingWidthaEditField  matlab.ui.control.NumericEditField
        WindowdiscriptionPanel          matlab.ui.container.Panel
        WindowHeightEditFieldLabel      matlab.ui.control.Label
        WindowHeightEditField           matlab.ui.control.NumericEditField
        WindowWidthEditFieldLabel       matlab.ui.control.Label
        WindowWidthEditField            matlab.ui.control.NumericEditField
        mmLabel                         matlab.ui.control.Label
        mmLabel_2                       matlab.ui.control.Label
        AreaofWindowEditFieldLabel      matlab.ui.control.Label
        AreaofWindowEditField           matlab.ui.control.NumericEditField
        mm2Label                        matlab.ui.control.Label
        DiameterofCircumsribingCircleEditFieldLabel  matlab.ui.control.Label
        DiameterofCircumsribingCircleEditField  matlab.ui.control.NumericEditField
        mmLabel_7                       matlab.ui.control.Label
        FramedescriptionPanel           matlab.ui.container.Panel
        DepthofframeEditFieldLabel      matlab.ui.control.Label
        DepthofframeEditField           matlab.ui.control.NumericEditField
        HeightofFrameEditFieldLabel     matlab.ui.control.Label
        HeightofFrameEditField          matlab.ui.control.NumericEditField
        WidthofframeEditFieldLabel      matlab.ui.control.Label
        WidthofframeEditField           matlab.ui.control.NumericEditField
        mmLabel_9                       matlab.ui.control.Label
        mmLabel_6                       matlab.ui.control.Label
        mmLabel_15                      matlab.ui.control.Label
        DistancebetweenadjacentcoreEditFieldLabel  matlab.ui.control.Label
        DistancebetweenadjacentcoreEditField  matlab.ui.control.NumericEditField
        YokeDiscriptionPanel            matlab.ui.container.Panel
        HeightofyokeEditFieldLabel      matlab.ui.control.Label
        HeightofyokeEditField           matlab.ui.control.NumericEditField
        GrossareaofyokeEditFieldLabel   matlab.ui.control.Label
        GrossareaofyokeEditField        matlab.ui.control.NumericEditField
        DepthofyokeEditFieldLabel       matlab.ui.control.Label
        DepthofyokeEditField            matlab.ui.control.NumericEditField
        mmLabel_3                       matlab.ui.control.Label
        mmLabel_4                       matlab.ui.control.Label
        mm2Label_2                      matlab.ui.control.Label
        MaterialusedEditFieldLabel      matlab.ui.control.Label
        MaterialusedEditField           matlab.ui.control.EditField
        IronAreaEditFieldLabel          matlab.ui.control.Label
        IronAreaEditField               matlab.ui.control.NumericEditField
        mm2Label_5                      matlab.ui.control.Label
        mmLabel_17                      matlab.ui.control.Label
        WindingDesignTab                matlab.ui.container.Tab
        WINDINGDESIGNPanel              matlab.ui.container.Panel
        PrimaryWindingtypeEditFieldLabel  matlab.ui.control.Label
        PrimaryWindingtypeEditField     matlab.ui.control.EditField
        SecondaryWindingTypeEditFieldLabel  matlab.ui.control.Label
        SecondaryWindingTypeEditField   matlab.ui.control.EditField
        HVspecificationPanel            matlab.ui.container.Panel
        RadialWidthEditFieldLabel       matlab.ui.control.Label
        RadialWidthEditField            matlab.ui.control.NumericEditField
        NumberofTurnsEditFieldLabel     matlab.ui.control.Label
        NumberofTurnsEditField          matlab.ui.control.NumericEditField
        HVcurrentEditFieldLabel         matlab.ui.control.Label
        HVcurrentEditField              matlab.ui.control.NumericEditField
        WindingEditFieldLabel           matlab.ui.control.Label
        WindingEditField                matlab.ui.control.EditField
        AreaofconductorEditFieldLabel   matlab.ui.control.Label
        AreaofconductorEditField        matlab.ui.control.NumericEditField
        AxialHeightEditFieldLabel       matlab.ui.control.Label
        AxialHeightEditField            matlab.ui.control.NumericEditField
        OutsidediameterEditFieldLabel   matlab.ui.control.Label
        OutsidediameterEditField        matlab.ui.control.NumericEditField
        WithTappingEditFieldLabel       matlab.ui.control.Label
        WithTappingEditField            matlab.ui.control.NumericEditField
        NumberoflayerEditFieldLabel     matlab.ui.control.Label
        NumberoflayerEditField          matlab.ui.control.NumericEditField
        TurnsLabel                      matlab.ui.control.Label
        TurnsLabel_2                    matlab.ui.control.Label
        ALabel                          matlab.ui.control.Label
        mm2Label_3                      matlab.ui.control.Label
        mmLabel_18                      matlab.ui.control.Label
        mmLabel_19                      matlab.ui.control.Label
        mmLabel_20                      matlab.ui.control.Label
        mmLabel_21                      matlab.ui.control.Label
        Amm2Label_3                     matlab.ui.control.Label
        currentDensityEditFieldLabel    matlab.ui.control.Label
        currentDensityEditField         matlab.ui.control.NumericEditField
        NumberofCoilsEditFieldLabel     matlab.ui.control.Label
        NumberofCoilsEditField          matlab.ui.control.NumericEditField
        mmLabel_31                      matlab.ui.control.Label
        NumberofParallelConductorEditField_2Label  matlab.ui.control.Label
        NumberofParallelConductorEditField_2  matlab.ui.control.NumericEditField
        LVspecificationPanel            matlab.ui.container.Panel
        NumberofturnsEditFieldLabel     matlab.ui.control.Label
        NumberofturnsEditField          matlab.ui.control.NumericEditField
        LVCurrentEditFieldLabel         matlab.ui.control.Label
        LVCurrentEditField              matlab.ui.control.NumericEditField
        AreaofConductorEditFieldLabel   matlab.ui.control.Label
        AreaofConductorEditField        matlab.ui.control.NumericEditField
        AxialheightEditFieldLabel       matlab.ui.control.Label
        AxialheightEditField            matlab.ui.control.NumericEditField
        RadialwidthEditFieldLabel       matlab.ui.control.Label
        RadialwidthEditField            matlab.ui.control.NumericEditField
        OutsideDiameterEditFieldLabel   matlab.ui.control.Label
        OutsideDiameterEditField        matlab.ui.control.NumericEditField
        InsidediameterEditFieldLabel    matlab.ui.control.Label
        InsidediameterEditField         matlab.ui.control.NumericEditField
        WindingEditField_2Label         matlab.ui.control.Label
        WindingEditField_2              matlab.ui.control.EditField
        mmLabel_22                      matlab.ui.control.Label
        mmLabel_23                      matlab.ui.control.Label
        mmLabel_24                      matlab.ui.control.Label
        mmLabel_25                      matlab.ui.control.Label
        mm2Label_4                      matlab.ui.control.Label
        ALabel_2                        matlab.ui.control.Label
        NumberofParallelConductorEditFieldLabel  matlab.ui.control.Label
        NumberofParallelConductorEditField  matlab.ui.control.NumericEditField
        TurnsLabel_3                    matlab.ui.control.Label
        ThicknessofconductorEditFieldLabel  matlab.ui.control.Label
        ThicknessofconductorEditField   matlab.ui.control.NumericEditField
        WidthofconductorEditFieldLabel  matlab.ui.control.Label
        WidthofconductorEditField       matlab.ui.control.NumericEditField
        mmLabel_26                      matlab.ui.control.Label
        mmLabel_27                      matlab.ui.control.Label
        CurrentDensityEditField_2Label  matlab.ui.control.Label
        CurrentDensityEditField_2       matlab.ui.control.NumericEditField
        Amm2Label_2                     matlab.ui.control.Label
        InsideDiameterEditFieldLabel    matlab.ui.control.Label
        InsideDiameterEditField         matlab.ui.control.NumericEditField
        RegulationTab                   matlab.ui.container.Tab
        ResistanceReactanceRegulationPanel  matlab.ui.container.Panel
        ResistanceEditFieldLabel        matlab.ui.control.Label
        ResistanceEditField             matlab.ui.control.NumericEditField
        ResistanceinpuEditFieldLabel    matlab.ui.control.Label
        ResistanceinpuEditField         matlab.ui.control.NumericEditField
        ReactanceEditFieldLabel         matlab.ui.control.Label
        ReactanceEditField              matlab.ui.control.NumericEditField
        ReactanceinpuEditFieldLabel     matlab.ui.control.Label
        ReactanceinpuEditField          matlab.ui.control.NumericEditField
        ImpedanceinpuEditFieldLabel     matlab.ui.control.Label
        ImpedanceinpuEditField          matlab.ui.control.NumericEditField
        RegulationEditFieldLabel        matlab.ui.control.Label
        RegulationEditField             matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        InsulationTypeEditFieldLabel    matlab.ui.control.Label
        InsulationTypeEditField         matlab.ui.control.EditField
        LossefficiencyTab               matlab.ui.container.Tab
        LossEfficiencyPanel             matlab.ui.container.Panel
        LossesPanel                     matlab.ui.container.Panel
        CopperLossEditFieldLabel        matlab.ui.control.Label
        CopperLossEditField             matlab.ui.control.NumericEditField
        StraylosswithCulossEditFieldLabel  matlab.ui.control.Label
        StraylosswithCulossEditField    matlab.ui.control.NumericEditField
        LossacrossLimbEditFieldLabel    matlab.ui.control.Label
        LossacrossLimbEditField         matlab.ui.control.NumericEditField
        LossacrossYokeEditFieldLabel    matlab.ui.control.Label
        LossacrossYokeEditField         matlab.ui.control.NumericEditField
        TotalCoreLossEditFieldLabel     matlab.ui.control.Label
        TotalCoreLossEditField          matlab.ui.control.NumericEditField
        TotalLossEditFieldLabel         matlab.ui.control.Label
        TotalLossEditField              matlab.ui.control.NumericEditField
        WattLabel                       matlab.ui.control.Label
        WattLabel_3                     matlab.ui.control.Label
        WattLabel_4                     matlab.ui.control.Label
        WattLabel_5                     matlab.ui.control.Label
        WattLabel_6                     matlab.ui.control.Label
        EfficiencyPanel                 matlab.ui.container.Panel
        FullLoadEfficiencyEditFieldLabel  matlab.ui.control.Label
        FullLoadEfficiencyEditField     matlab.ui.control.NumericEditField
        HalfLoadEfficiencyEditFieldLabel  matlab.ui.control.Label
        HalfLoadEfficiencyEditField     matlab.ui.control.NumericEditField
        MaximumEfficiencyoccuratEditFieldLabel  matlab.ui.control.Label
        MaximumEfficiencyoccuratEditField  matlab.ui.control.NumericEditField
        offullloadLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        WattLabel_2                     matlab.ui.control.Label
        TankDesignTab                   matlab.ui.container.Tab
        TankDesignPanel                 matlab.ui.container.Panel
        HeightoftankEditFieldLabel      matlab.ui.control.Label
        HeightoftankEditField           matlab.ui.control.NumericEditField
        WidthofTankEditFieldLabel       matlab.ui.control.Label
        WidthofTankEditField            matlab.ui.control.NumericEditField
        LengthofTankEditFieldLabel      matlab.ui.control.Label
        LengthofTankEditField           matlab.ui.control.NumericEditField
        TemperatureRiseEditFieldLabel   matlab.ui.control.Label
        TemperatureRiseEditField        matlab.ui.control.NumericEditField
        WallareaofeachtubeEditFieldLabel  matlab.ui.control.Label
        WallareaofeachtubeEditField     matlab.ui.control.NumericEditField
        NumberoftubesEditFieldLabel     matlab.ui.control.Label
        NumberoftubesEditField          matlab.ui.control.NumericEditField
        mmLabel_28                      matlab.ui.control.Label
        mmLabel_29                      matlab.ui.control.Label
        mmLabel_30                      matlab.ui.control.Label
        degreeCelciusLabel_2            matlab.ui.control.Label
        mm2Label_6                      matlab.ui.control.Label
        CoolingTypeEditFieldLabel       matlab.ui.control.Label
        CoolingTypeEditField            matlab.ui.control.EditField
        ExpensesTab                     matlab.ui.container.Tab
        ExpenditurePanel                matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        EditPriceButton                 matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        GenerateEstiamtionButton        matlab.ui.control.Button
        LoadValueButton                 matlab.ui.control.Button
        UITable2                        matlab.ui.control.Table
        ReportGeneratorTab              matlab.ui.container.Tab
        ReportGeneratorPanel            matlab.ui.container.Panel
        ReportFormatDropDownLabel       matlab.ui.control.Label
        ReportFormatDropDown            matlab.ui.control.DropDown
        GenerateReportButton            matlab.ui.control.Button
        EditField_2                     matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        function results = designWindow(app)
            global Q
            global Vp
            global Vs
            global p
            global Bm
            global h_w
            global k
            global f
            global Et
            global d
            global a
            global b
            global c
            global e
            global Hw
            global D
            global Ai
            global yoke_area yoke_vol
            global W
            global total_core_loss
            global H
            global Kw
            
            app.EditField.Value=k
            Et= k* sqrt(Q/1000); %emf per turn
            Ai = Et*1000000/(4.44*f*Bm); % area of core
            Agi= Ai/0.9;  %gross area of Core using stacking factor 0.9
            if(strcmp(app.CoretypeDropDown.Value, 'Square'))
                z=0.45;
                d = sqrt(Ai/z);
                a= d/sqrt(2);
                app.DiameterofCircumsribingCircleEditField.Value= d;
                app.LargestStampingWidthaEditField.Value= a;
                app.SmallestWidthbEditField.Value= 0;
                app.ndLargestWidthEditField.Value=0;
                app.rdLargestWidthEditField.Value=0;
            elseif(strcmp(app.CoretypeDropDown.Value, 'Cruciform'))
                z=0.56;
                d = sqrt(Ai/z);
                a= 0.85*d;
                b= 0.53*d;
                app.DiameterofCircumsribingCircleEditField.Value=d;
                app.LargestStampingWidthaEditField.Value= a;
                app.SmallestWidthbEditField.Value= b;
                app.ndLargestWidthEditField.Value=0;
                app.rdLargestWidthEditField.Value=0;
            elseif(strcmp(app.CoretypeDropDown.Value, 'Three Stepped'))
                z=0.6;
                d = sqrt(Ai/z);
                a= 0.90*d;
                b= 0.42*d;
                c= 0.7*d;
                app.DiameterofCircumsribingCircleEditField.Value= d;
                app.LargestStampingWidthaEditField.Value= a;
                app.SmallestWidthbEditField.Value= b;
                app.ndLargestWidthEditField.Value=c;
                app.rdLargestWidthEditField.Value=0;
                
                
            else
                z=0.62;
                d = sqrt(Ai/z);
                b=0.36*d;
                a=0.92*d;
                c=0.78*d;
                e= 0.60*d;
                app.DiameterofCircumsribingCircleEditField.Value= d;
                app.LargestStampingWidthaEditField.Value= a;
                app.SmallestWidthbEditField.Value= b;
                app.ndLargestWidthEditField.Value=c;
                app.rdLargestWidthEditField.Value=e;
            end


            if(Vp>Vs)
                s=Vp*0.001+30;
                
            else
                s=Vs*0.001+30;
            end
            if(Q<=200000)   %space factor
                Kw= 8/s;
            elseif(Q<=700000)&&(Q>200000)
                Kw=10/s;
            else
                Kw=12/s;
            end
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                AW= Q*1000000/(2.22*f*Bm*Kw*p*Ai); %define Kw
            else
                AW= Q*1000000/(3.33*f*Bm*Kw*p*Ai); % in mm'2
            end
            
            Ww= sqrt(AW/h_w); %width of window


            Hw= Ww*h_w; %height of window
            
            Aw= Hw*Ww; % Area of Window
            
            
            app.AreaofWindowEditField.Value= Aw;
            app.WindowWidthEditField.Value= Ww;
            app.WindowHeightEditField.Value=Hw;
            D=Ww+d; %distance between adjacent core
            
            
            if(strcmp(app.CoreMaterialDropDown.Value, 'Hot Rolled Steel'))
                app.MaterialusedEditField.Value='Hot Rolled Steel';
                proportion= 1.2;
                
                
            else
                app.MaterialusedEditField.Value='Cold Rolled Steel';
                proportion= 1;
            end
            yoke_Bm= Bm/proportion;
            yoke_area= proportion*Ai;
            gross_yoke_area= yoke_area/0.9;
            Dy= a; %depth of yoke / depth of frame
            
            
            Hy= gross_yoke_area/Dy;
            
            
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                H= Hw+2*Hy; %height of the frame
                W= D+a; %width of the frame
                wgt_limb=2*(Hw+D+a)*Ai*7.6*10.^-6%for limb loss
                yoke_vol= yoke_area*D
                %there is no yoke loss in single phase
                limb_loss= wgt_limb*proportion;
                total_core_loss= limb_loss*proportion;
                yoke_loss=0;


            else
                H= Hw+2*Hy; %height of the frame
                W= 2*D+a; %width of the frame
                wgt_limb= 3*0.3*Ai*7.6/1000 %taking density of lamination 7.6*10^3
                wgt_two_yoke=2*W*yoke_area*7.6*10^-6;
                yoke_loss= (Bm/proportion)*wgt_two_yoke;
                limb_loss= wgt_limb*proportion;
                yoke_vol= yoke_area*D*2
                total_core_loss= yoke_loss+limb_loss;
            end
            app.LossacrossLimbEditField.Value=limb_loss;
            app.LossacrossYokeEditField.Value=yoke_loss;
            app.TotalCoreLossEditField.Value= total_core_loss;
            
            
            app.DistancebetweenadjacentcoreEditField.Value=D;
            app.HeightofFrameEditField.Value= H;
            app.DepthofframeEditField.Value= Dy;
            app.DepthofyokeEditField.Value= Dy;
            app.HeightofyokeEditField.Value= Hy;
            app.GrossareaofyokeEditField.Value= gross_yoke_area;
            app.WidthofframeEditField.Value= W;
            
            app.IronAreaEditField.Value= Ai
            
            
        end
        
        function results = Winding_design(app)
            global Q;
            
            global p
            global Bm
            global Et
            global Alw
            global Il
            global VS
            global VP
            global lt
            global lowturn
            global highturn
            global ht
            global HV
            global no
            global Ht
            global Ih


            no=0;
            %for low voltage winding design
            if(VP>VS)
                c=VS;
                d=VP;
                app.WindingEditField.Value= 'Primary';
                app.WindingEditField_2.Value= 'Secondary';
            else
                c=VP;
                d=VS
                app.WindingEditField.Value= 'Secondary';
                app.WindingEditField_2.Value= 'Primary';
            end
            lowturn= c/Et;
            highturn = (d*lowturn)/c;
            
            lt=fix(lowturn)+1; %number of turn
            Ht = fix(highturn)+1;
            tap = app.TappingEditField.Value;
            tapping= tap/100;
            ht= Ht+tapping*Ht;% HT with tapping
            HV= d;
            B=Bm*lowturn/lt; %new Max Bm
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                Il=Q/c;
                Ih= Q/d; % Hv current
            else
                Il= Q/(3*c); %low voltage current
                Ih= Q/(3*d); % Hv current
            end
            
            
            Alw= Il/p; % area of Low voltage Conductor
            
            app.NumberofTurnsEditField.Value= Ht;
            app.WithTappingEditField.Value= ht;
            
            iteration=0;
            Lv_winding(app,iteration);


        end
        
        
        function results = Lv_winding(app,iteration)
            global Il
            global lt
            global d
            global Outside_dia_Lv
            global Inside_dia_Lv
            global A 
            global new_area_lv
            global LcL vol_lv
            global bs
            global Alw
            global number
          
            if(Alw>25)
                number=fix(Alw/25)+1; % number of parallel conductor
                A=Alw/number;
            else
                A=Alw;
                number=1;
            end
            A=A+iteration;
            ins_layer = app.InsulationWidthEditField.Value; %insulation width
            count=1;
            m=3;
            a=1000;
            set= ones (a,m);
            data= xlsread('Book1.xlsx');
            for i= 2:+1:33
                for j= 2:+1:28
                    area = data(i,j);
                    b= area-A;
                    if(b>0) && (b<0.5)
                        set(count,1)= area;
                        set(count,2)= data(1,j);      %thickness
                        set(count,3)=data(i,1);
                        count=count+1;
                    end
                end
            end
            r = fix(count/2)
            area = set(r,1);
            thick = set(r,2);
            width = set(r,3);
            
            ins_thick= thick + ins_layer;
            ins_width= width;
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                new_area_lv = thick*width*number;
                LcL= ((lt/2)+1)*((width*thick)+1); %axial depth of Lv winding
                per_strip_area=thick*width;
                new_p = Il/new_area_lv;
                %bs= (3*(width+0.5))+0.5; %radial depth of Lv winding
                bs=(5*thick+1);
                 app.CurrentDensityEditField_2.Value=new_p;
            else
                new_area_lv = thick* width*number;
                new_p = Il/new_area_lv;
                LcL= ((lt/3)+1)* thick; %axial depth of Lv winding
                bs= (3*(width+0.5))+1; %radial depth of Lv winding
                 app.CurrentDensityEditField_2.Value=new_p;
            end
            Inside_dia_Lv = d+3;
            Outside_dia_Lv = Inside_dia_Lv + 2*bs;
            vol_lv= 3.1415*((Outside_dia_Lv + Inside_dia_Lv)/2)*new_area_lv*lt;
            app.NumberofturnsEditField.Value= lt;
            app.ThicknessofconductorEditField.Value= thick;
            app.WidthofconductorEditField.Value=width;
            app.LVCurrentEditField.Value= Il;
            app.AreaofConductorEditField.Value= new_area_lv;
            app.AxialheightEditField.Value=LcL;
            app.RadialwidthEditField.Value=bs;
           
            app.NumberofParallelConductorEditField.Value= number;
            app.OutsideDiameterEditField.Value= Outside_dia_Lv;
            app.InsidediameterEditField.Value= Inside_dia_Lv;
            Hv_winding(app,iteration);
            
            
        end


        function results = Hv_winding(app, iteration)
            global ht
           
            global HV
            global Et
            global Outside_dia_Lv
            global Ih
            global D
            global mod_area
            global duct_width
            global LcH 
            global Outside_dia_Hv
            global Inside_dia_Hv
            global bh ax_depth vol_hv norm_coil layer
            
            
            if (HV >1500)
                coil= fix(HV/1300); % number of coil
            else
                coil=1;
            end
            norm_coil = ht/coil; % no of turn in each coil
            rein_coil = ht-(norm_coil*coil); %reinforced coil
            limit= 150/(2*Et); %turns per layer limit
            no_layer= (norm_coil/limit); % no of layer per coil
            layer=fix(no_layer)+1;
            
            area_hv= (Ih/2.4)+iteration; % taking standard HV current density
            if(area_hv<6)
                dia= sqrt(1.27323954474*area_hv);
                m=2
                n=60;
                pet= ones (n,m);
                cnt=1;
                data= xlsread('Hv_book.xlsx');
                for i= 1:+1:56
                    d = data(i,1);
                    b = dia-d;
                    if(b>0)
                        pet(cnt,1)= data(i,1)
                        pet(cnt,2)= data(i,2);
                        cnt= cnt+1;
                    end
                end
               % end
                
                ins_dia= pet(cnt-1,2); %with insulator diameter
                bare_dia = pet(cnt-1,1);
                mod_area = (0.7853981634*bare_dia*bare_dia);%modified diameter
                ax_depth= limit*ins_dia; %
                bh= layer*ins_dia+ (layer-1)*0.3; %radial depth of hv % 0.3 as insulation
                duct_width= (5+0.9*HV/1000);
                Inside_dia_Hv = Outside_dia_Lv+2*duct_width; %radial duct width
                Outside_dia_Hv = Inside_dia_Hv+ 2*bh;
                den=Ih/mod_area;
               
            else
                if(area_hv>25)
                nu=fix(area_hv/25)+1; % number of parallel conductor
                A=area_hv/nu;
            else
                A=area_hv;
                nu=1;
            end
                count=1;
                m=3;
                n=1000;
                set= ones (n,m);
                data= xlsread('Book1.xlsx');
                for i= 2:+1:33
                    for j= 2:+1:28
                        area = data(i,j);
                        b= area-A;
                        if(b>0) && (b<0.5)
                            set(count,1)= area;
                            set(count,2)= data(1,j);      %thickness
                            set(count,3)=data(i,1);
                            count=count+1;
                        end
                    end
                end
                
app.NumberofParallelConductorEditField_2.Value=nu;                
                %area = set(r,1);
                thick = set(fix(count/2),2);
                width = set(fix(count/2),3);
                mod_area = thick* width;
                den=Ih/mod_area;


                if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                    ax_depth= (((ht))+9.5) ; %axial depth of Lv winding
                    bh=width;
                    duct_width= (5+0.9*HV/1000);
                    Inside_dia_Hv = Outside_dia_Lv+2*duct_width; %radial duct width
                    Outside_dia_Hv = Inside_dia_Hv+ 4*bh;
                else
                    
                    ax_depth= ((ht/3)+1)* thick; %axial depth of Lv winding
                    bh=width;
                    duct_width= (5+0.9*HV/1000);
                    Inside_dia_Hv = Outside_dia_Lv+2*duct_width; %radial duct width
                    Outside_dia_Hv = Inside_dia_Hv+ 2*bh;
                    
                end
                
            end
            
             app.currentDensityEditField.Value=den;
            LcH= (coil+1)*(ax_depth + 5); % 5 mm for spacer, axial length of high voltage winding
            vol_hv=3.1415*((Outside_dia_Hv+ Inside_dia_Hv)/2)*ht*mod_area;
            
            
            clearance= D-Outside_dia_Hv;
            
            
            app.HVcurrentEditField.Value= Ih;
            app.AreaofconductorEditField.Value= mod_area;
            app.AxialHeightEditField.Value=  ax_depth;
            app.NumberoflayerEditField.Value= layer;
            app.RadialWidthEditField.Value= bh;
            app.NumberofCoilsEditField.Value=coil;
            app.OutsidediameterEditField.Value= Outside_dia_Hv;
            app.InsideDiameterEditField.Value= Inside_dia_Hv;
            Calculate_Resistance(app,iteration)
            
        end


        function results = Calculate_Resistance(app,iteration)
            
            
            global Inside_dia_Hv
            global Outside_dia_Hv
            global Inside_dia_Lv
            global Outside_dia_Lv
            global mod_area
            global new_area_lv
            global Ht Ai
            global lt
            global Ih %high voltage current
            global HV %high voltage
            global Rhv_pu
            global duct_width
            global ax_depth % axial length of hv
            global bh %radial depth of hv
            global LcL %axial length of lv
            global bs 
            global f
            global Rhv mean_hv lnt_lv
            global vol_wind vol_core 
            global number
          
                
                resistivity= 0.021;
          
            pf= app.pfEditField.Value;
            mean_hv= (Inside_dia_Hv+Outside_dia_Hv)/2; %mean diameter of high voltage winding
            lnt_hv= 3.1415*mean_hv; % length of mean turn of Hv winding
            resistance_hv= (Ht*resistivity*lnt_hv)/ (mod_area*1000);
            mean_lv=(Inside_dia_Lv+Outside_dia_Lv)/2; % mean diameter of low voltage winding
            lnt_lv= 3.1415*mean_lv; % length of mean turn of Lv winding
            resistance_lv= (lt*resistivity*lnt_lv)/(new_area_lv*1000);
            resistance_lv= resistance_lv/number;
            e=Ht/lt;
            mean_wind= (Inside_dia_Lv+Outside_dia_Hv)/2; %mean diameter of winding
            len_mean_turn= 3.1415*mean_wind; % length of mean turn
            hgt_mean_wind= (ax_depth+LcL)/2; %mean height of winding
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                reactance=4.6*3.1415*f*3.1415*Ht*Ht*(len_mean_turn/hgt_mean_wind)*(5*duct_width/4 + ((2*bh+bs)/3))*10.^(-10);
                
            else
                reactance= 8*3.1415*3.1415*f*Ht*Ht*(len_mean_turn/hgt_mean_wind)*(duct_width + (bh+bs)/3)*10.^(-10);
                
            end
            Rhv= resistance_hv+(e.^2*resistance_lv); %total resistance referred to the high voltage side
            Rhv_pu= (Ih*Rhv)/HV;
            reactance_pu= (Ih*reactance)/HV;
            impedance_pu=sqrt(Rhv_pu*Rhv_pu+ reactance_pu*reactance_pu);
            regulation_pu = Rhv_pu*pf+reactance_pu*sqrt(1-pf*pf);
            vol_wind= 3.1415*mean_wind*mean_wind*0.785*hgt_mean_wind
            vol_core=Ai*hgt_mean_wind
            
            app.ResistanceEditField.Value= Rhv;
            app.ResistanceinpuEditField.Value=Rhv_pu
            app.ReactanceEditField.Value=reactance
            app.ReactanceinpuEditField.Value=reactance_pu
            app.ImpedanceinpuEditField.Value=impedance_pu
            app.RegulationEditField.Value= regulation_pu
            app.InsulationTypeEditField.Value='F'
            app.CoolingTypeEditField.Value='ONAN'
            loss_efficiency(app,iteration)


        end
        
        function results = loss_efficiency(app,iteration)
            
            global Rhv
            global total_core_loss
            global Q
            global Ih
            global total_loss
            
            
            Cu_loss= 3*Ih*Ih*Rhv; %I^2 loss at 75 celcius
            stray_loss= 1.15*Cu_loss; %cu loss with stray loss
            total_loss=stray_loss+total_core_loss;% total core loss= yoke loss +limb loss
            eff= (Q/(Q+total_loss))*100
           
           if(eff<97.5 && iteration<2)
                iteration=iteration+0.2;
               Lv_winding(app,iteration);
            else
               iteration=0
           end
           Cu_loss= 3*Ih*Ih*Rhv; %I^2 loss at 75 celcius
            stray_loss= 1.15*Cu_loss; %cu loss with stray loss
            total_loss=stray_loss+total_core_loss;% total core loss= yoke loss +limb loss
                 efficiency= (Q/(Q+total_loss))*100
                
            half_efficiency=100*(0.5*Q)/(0.5*Q+stray_loss/4+total_core_loss);
            x=sqrt(total_core_loss/stray_loss)*100 % x is the ratio of max efficient load with full load
            
            app.CopperLossEditField.Value= Cu_loss;
            app.StraylosswithCulossEditField.Value=stray_loss;
            app.TotalCoreLossEditField.Value= total_core_loss;
            app.FullLoadEfficiencyEditField.Value= efficiency;
            app.TotalLossEditField.Value=total_loss;
            app.MaximumEfficiencyoccuratEditField.Value=x;
            app.HalfLoadEfficiencyEditField.Value= half_efficiency;


        end
        
        function results = Tank_design(app)
            global H %height of the frame
            global D  %distance between adjacent core
            global HV
            global Outside_dia_Hv
            global total_loss
            global Q
            global vol
            
            temperature=app.SelectTheMaximumTemperatureEditField.Value
            if((HV/1000)<11)%||(HV/1000)=11)
                L=30; %clearance at base with core and tank
                B=20
            else((HV/1000)>11)
                B=40;
                L=30;
            end
            ht_tank=H+L+350; %350 as oil above core and base as well lead space
            if((ht_tank-fix(ht_tank))>0)
                ht=fix(ht_tank/50)+1;
            else
                ht=ht_tank
            end
            height_tank=ht*50
            wh_tank=2*D+Outside_dia_Hv+2*B;
            wt=fix(wh_tank/40)+1;
            length_tank=wt*40
            l_tank= Outside_dia_Hv+2*L %length of the tank
            lth=fix(l_tank/50)+1;
           tank_width = lth*50;
            disp_area=2*(tank_width+length_tank)*height_tank;
            vl= tank_width*length_tank*height_tank;
            temp= (total_loss*10.^6)/(disp_area*12.5);
            if(temp>temperature)
                
                var=(1/8.8)*(total_loss/(disp_area+temp)+12.5);
                area_tube= disp_area*var;
                num_tube= area_tube/164850; %164850 is the assumed area of per tube in mm'2
                v=  0.058875*1000000 %in mm cube
                vol_tube=num_tube*v;%height of the tube is assumed as 1.5 m
                vol=vl+vol_tube;
                
                
            else
                num_tube=0;
                vol=vl
            end
            
            app.HeightoftankEditField.Value=height_tank
            app.WidthofTankEditField.Value=tank_width
            app.LengthofTankEditField.Value= length_tank
            app.TemperatureRiseEditField.Value= temp
            app.WallareaofeachtubeEditField.Value=164850
            app.NumberoftubesEditField.Value=num_tube
            
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable.Enable = 'off';
            app.UITable2.Enable='off'
            app.SaveButton.Visible= 'off'
            name= readtable('Name.xlsx')
            nm=name.Var1;
            unit=name.Var2;
            data={'','',''};
            rata={'',''};
            for i= 1:+1:20
                data(i,1)={i};
                data(i,2)= nm(i);
                data(i,3)= unit(i);
            end
            app.UITable2.Data=data;
            app.UITable.Data=rata;
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            global Q;
            global Vp
            global Vs
            global VS
            global VP
            global p
            global Bm
            global h_w
            global k
            global f
            global pr
            global se


            inputKVA = app.TransformerCapacityEditField.Value;
            if(strcmp(app.DropDown.Value, 'KVA'))
                Q= inputKVA * 1000;
                
            else
                Q= inputKVA;
            end


            Primary_Voltage = app.PrimarywindingvoltageEditField.Value;
            if(strcmp(app.DropDown_2.Value, 'V'))
                Vp= Primary_Voltage;
            elseif(strcmp(app.DropDown_2.Value, 'KV'))
                Vp= Primary_Voltage*1000;
            else
                Vp= Primary_Voltage*1000000;
            end
            
            Secondary_Voltage = app.SecondarywindingvoltageEditField.Value;
            if(strcmp(app.DropDown_3.Value, 'V'))
                Vs= Secondary_Voltage;
            elseif(strcmp(app.DropDown_3.Value, 'KV'))
                Vs= Secondary_Voltage*1000;
            else
                Vs= Secondary_Voltage*1000000;
            end
            
            p = app.CurrentDensityEditField.Value;
            Bm = app.MaxMagneticfluxdensityEditField.Value; %max magnetic field
            h_w = app.RatioofwindowheighttowindowwidthinDecimalformEditField.Value;
            temp = app.SelectTheMaximumTemperatureEditField.Value;
            if(strcmp(app.frequencyDropDown.Value, '50 Hz'))
                f = 50;
            else
                f = 60;
            end
            
            if(strcmp(app.WindingtypeDropDown.Value, 'Star-Star'))
                VP= Vp/sqrt(3);
                VS= Vs/sqrt(3);
                pr='Star';
                se='Star';
                
            elseif(strcmp(app.WindingtypeDropDown.Value, 'Star-Delta'))
                VP= Vp/sqrt(3);
                VS= Vs;
                pr='Star';
                se='Delta';
                
            elseif(strcmp(app.WindingtypeDropDown.Value, 'Delta-Delta')|| strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                
                VP= Vp;
                VS= Vs;
                if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                    pr='null'
                    se='null'
                else
                    pr='Delta';
                    se='Delta';
                end
                
            else
                
                VP= Vp;
                VS= Vs/sqrt(3);
                pr='Delta';
                se='Star';
                
            end
            
            
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                k= 0.8;
                
                
            elseif(strcmp(app.TypeDropDown.Value, 'Three phase core type( distribution)'))
                k=0.45;
                
            else
                k= 0.65;
                
            end
            app.OutputConstantkEditField.Value=k;
            app.PrimaryWindingtypeEditField.Value=pr;
            app.SecondaryWindingTypeEditField.Value=se;
            designWindow(app);
            Winding_design(app);
            %Calculate_Resistance(app);
           % loss_efficiency(app);
            Tank_design(app);
         app.EditField.Value=randi(100)
         app.Lamp.Color=[0,1,0]


        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
            import mlreportgen.report.*
            import mlreportgen.dom.*
            import mlreportgen.report.Report.*
            
            
            global Vp
            global Vs
            
            global k Kw
            global f
            global Et
            global Q
            
            global Il
            global VS
            global VP
            global Outside_dia_Hv Inside_dia_Lv Outside_dia_Lv Inside_dia_Hv
           
            global Ih
            rata=ones(20,6);
            Uip=ones(20,3);
            Uis=ones(20,3);
            if(k==0.8)
                phase= 'single phase';
            else
                phase= 'three phase';
            end
             if(strcmp(app.WindingEditField.Value,'delta'))
            lh=Ih*sqrt(3);
            else
              lh=Ih  %line current Lv
             end
              if(strcmp(app.WindingEditField_2.Value,'delta'))
          ll= Il*sqrt(3);
            else
              ll=Il
              end
              %for i= 1:+1:20 
                 % for j=1:+1:4
                 
            wind_type = app.WindingtypeDropDown.Value;
            
            Company_Name = app.COMPANYNAMEEditField.Value;
            x= fix(clock);
            y=x(6)+x(5)*100+x(4)*10000+x(3)*1000000+x(2)*100000000+x(1)*10000000000; %for different report name variable
            if(strcmp(app.ReportFormatDropDown.Value, 'pdfs'))
                rep=Document(['Transformer Report ' num2str(y)],'pdf', 'template.dotx')
            elseif(strcmp(app.ReportFormatDropDown.Value, ' docx (word file)'))
                rep=Document(['Transformer Report ' num2str(y)],'docx', 'template.dotx')
            else
                rep=Document(['Transformer Report ' num2str(y)],'html', 'template.dotx')
                
            end
            open(rep)
            moveToNextHole(rep);
            append(rep,Company_Name);
            
            moveToNextHole(rep);
            append(rep,num2str(Q));
            moveToNextHole(rep);
            append(rep,phase);
            moveToNextHole(rep);
            append(rep,num2str(f));
            moveToNextHole(rep);
            append(rep,wind_type);
            moveToNextHole(rep);
            append(rep,num2str(Vp));
            moveToNextHole(rep);
            append(rep,num2str(VP));
            moveToNextHole(rep);
            append(rep,num2str(Vs));
            moveToNextHole(rep);
            append(rep,num2str(VS));
            moveToNextHole(rep);
            append(rep,num2str(Il));
            moveToNextHole(rep);
            append(rep,num2str(Ih));
            moveToNextHole(rep);
            append(rep,num2str(app.OutputConstantkEditField.Value)); %output constant k
            moveToNextHole(rep);
            append(rep,num2str(Et)); %emf per turn
            moveToNextHole(rep);
            append(rep,num2str(app.DiameterofCircumsribingCircleEditField.Value)); 
            moveToNextHole(rep);
            append(rep,num2str(app.CoretypeDropDown.Value)); %number of steps used in core
            moveToNextHole(rep);
            append(rep,num2str(app.LargestStampingWidthaEditField.Value));% dimension of largest stamping
            moveToNextHole(rep);
            append(rep,num2str(app.SmallestWidthbEditField.Value));%dimension of smallest stamping
            moveToNextHole(rep);
            append(rep,num2str(app.IronAreaEditField.Value));%net iron area
            moveToNextHole(rep);
            append(rep,num2str(app.MaxMagneticfluxdensityEditField.Value));%Flux density Bm
            moveToNextHole(rep);
            append(rep,num2str(app.DepthofyokeEditField.Value)); %depth of yoke
            moveToNextHole(rep);
            append(rep,num2str(app.HeightofyokeEditField.Value)); %height of yoke
            moveToNextHole(rep);
            append(rep,num2str(app.GrossareaofyokeEditField.Value));%Net yoke area
            moveToNextHole(rep);
            append(rep,num2str(app.TotalCoreLossEditField.Value));%iron loss
            moveToNextHole(rep);
            append(rep,num2str(Kw));%window space factor
            moveToNextHole(rep);
            append(rep,num2str(app.WindowHeightEditField.Value));%height of window
            moveToNextHole(rep);
            append(rep,num2str(app.WindowWidthEditField.Value));%Width of window
            moveToNextHole(rep);
            append(rep,num2str(app.AreaofWindowEditField.Value));%window area
            moveToNextHole(rep);
            append(rep,num2str(app.DistancebetweenadjacentcoreEditField.Value));%distance between adjacent limb
            moveToNextHole(rep);
            append(rep,num2str(app.HeightofFrameEditField.Value));%height of frame
            moveToNextHole(rep);
            append(rep,num2str(app.WidthofframeEditField.Value));%width of frame
            moveToNextHole(rep);
            append(rep,num2str(app.DepthofframeEditField.Value));%depth of frame
            moveToNextHole(rep);
            append(rep,app.PrimaryWindingtypeEditField.Value);%lv winding type
            moveToNextHole(rep);
            append(rep,app.SecondaryWindingTypeEditField.Value);%hv winding type
            moveToNextHole(rep);


            append(rep,num2str(app.WidthofconductorEditField.Value));%width
            moveToNextHole(rep);
            append(rep,num2str(sqrt(app.AreaofconductorEditField.Value)*1.275));%of hv
            moveToNextHole(rep);
            append(rep,num2str(app.ThicknessofconductorEditField.Value));%thickness
            moveToNextHole(rep);
           
            append(rep,num2str(app.AreaofConductorEditField.Value));%area of conductor Lv
            moveToNextHole(rep);
            append(rep,num2str(app.AreaofconductorEditField.Value));%of hv
            moveToNextHole(rep);
            append(rep,num2str(app.NumberofParallelConductorEditField.Value));% number of parallel conductor in  lv
            moveToNextHole(rep);
            append(rep,num2str(1));% number of parallel conductor in hv
            moveToNextHole(rep);


            append(rep,num2str(app.CurrentDensityEditField_2.Value));%current density in lv
            moveToNextHole(rep);
            append(rep,num2str(app.currentDensityEditField.Value));%current density in hv
            moveToNextHole(rep);
            append(rep,num2str(app.NumberofturnsEditField.Value));%turn per phase in lv
            moveToNextHole(rep);
            append(rep,num2str(app.NumberofTurnsEditField.Value));%turn per phase in hv
            moveToNextHole(rep);
            append(rep,num2str(0));%Coils total number in lv
            moveToNextHole(rep);


            append(rep,num2str(app.NumberofCoilsEditField.Value)); 
            moveToNextHole(rep);
            append(rep,num2str(app.AxialheightEditField.Value));%Height of winding
            moveToNextHole(rep);
            append(rep,num2str(app.AxialHeightEditField.Value)); %Hv
            moveToNextHole(rep);
            append(rep,num2str(app.RadialwidthEditField.Value)); %Depth of winding
            moveToNextHole(rep);
            append(rep,num2str(app.RadialWidthEditField.Value)); %HV
            moveToNextHole(rep);
            append(rep,num2str(Inside_dia_Lv));%Inside diameter of LV coil
            moveToNextHole(rep);
            append(rep,num2str(Inside_dia_Hv));%Inside diameter of Hv coil
            moveToNextHole(rep);
            append(rep,num2str(Outside_dia_Lv));%Outside diameter of LV Coil
            moveToNextHole(rep);
            append(rep,num2str(Outside_dia_Hv));%HV
            moveToNextHole(rep);
            append(rep,num2str(app.ResistanceEditField.Value));%Resistance referred to the HV side (LV)
            moveToNextHole(rep);
            append(rep,num2str(app.HeightoftankEditField.Value));%Tank Height
            moveToNextHole(rep);
            append(rep,num2str(app.LengthofTankEditField.Value));%Tank lengthe
            moveToNextHole(rep);
            append(rep,num2str(app.WidthofTankEditField.Value));%Tank width
            moveToNextHole(rep);
            append(rep,num2str(app.NumberoftubesEditField.Value)); %Number of Tubes
            moveToNextHole(rep);
            append(rep,num2str(app.TemperatureRiseEditField.Value));%Temperature Rise
            moveToNextHole(rep);
            append(rep,num2str(app.ResistanceinpuEditField.Value));%p.u. rresitance
            moveToNextHole(rep);
            append(rep,num2str(app.ReactanceinpuEditField.Value));%p.u. reactance
            moveToNextHole(rep);
            append(rep,num2str(app.ImpedanceinpuEditField.Value)); %p.u. impedance
            moveToNextHole(rep);
            append(rep,num2str(app.TotalCoreLossEditField.Value)); %total core loss
            moveToNextHole(rep);
            append(rep,num2str(app.StraylosswithCulossEditField.Value)); %total copper loss
           moveToNextHole(rep);
           append(rep,num2str(app.TotalLossEditField.Value));%total loss at full load
           moveToNextHole(rep);
           append(rep,num2str(app.FullLoadEfficiencyEditField.Value));%Efficiency at full load
            %moveToNextHole(rep);
           % append(rep,MATLABTable(rata));


            close(rep);
            app.EditField_2.Value=randi(100);
            
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                
                app.WindingtypeDropDown.Visible='off';
                app.WindingTypeLabel.Visible='off';
            else
                app.WindingtypeDropDown.Visible='on';
                app.WindingTypeLabel.Visible='on';
            end
        end

        % Button pushed function: EditPriceButton
        function EditPriceButtonPushed(app, event)
            
            if(strcmp(app.EditPriceButton.Text, 'Edit Price'))
                app.UITable.Editable = 'on';
            app.UITable2.Editable='off'
                app.EditPriceButton.Text='Cancel'
                app.SaveButton.Visible= 'on'
                app.GenerateEstiamtionButton.Visible='off'
                data= readtable('price.xlsx');
                
                pix=zeros(20,1)
                for i= 1:+1:20
                    
                        
                        rata(i,1)=data(i,1);
                end
                app.UITable.Data= rata;
            else
                app.UITable.Editable = 'off';
            app.UITable2.Editable='off'
                app.EditPriceButton.Text='Edit Price'
                app.GenerateEstiamtionButton.Visible='on'
                app.SaveButton.Visible= 'off'
            end
            
        end

        % Button pushed function: GenerateEstiamtionButton
        function GenerateEstiamtionButtonPushed(app, event)
             vc=ones(1,1)
        v=ones(1,1)
        vw=ones(1,1)
       vy=ones(1,1)
            global Q
            global vol %volume of Tank and tube included
            global vol_wind %volume of winding and core include
            global yoke_vol vol_hv vol_lv vol_core
      v=vol;
      vw=vol_wind
      vy=yoke_vol
      vc=vol_core
      vh=vol_hv
      vl=vol_lv
            data= readtable('price.xlsx')
            name= readtable('name.xlsx')
            unit=name.Var2;
            rate=data.Var1;
            name= name.Var1;
           quantity=zeros(20,1)
           v=ones(1,1)
           vlo=ones(1,1)
           cost=ones(20,1)
            if(strcmp(app.TypeDropDown.Value, 'Single Phase Core type'))
                
                vw(1,1)=vw(1,1).*2
                vy(1,1)=vy(1,1)
                vc(1,1)=vc(1,1).*2
                vh=vh*2
                vl=vl*2
            else
                vw(1,1)=vw(1,1).*3
                vy(1,1)=vy(1,1).*2
                vc(1,1)=vc(1,1).*3
                vh=vh*3
                vl=vl*3
            end
            for i= 1:+1:20
                if(strcmp(name(i),'Transformer Oil'))
                    v(1,1)= vol(1,1)-vw(1,1)-vy(1,1);
                     quantity(i,1)= v(1,1).*10.^-6;
                     cost(i,1)= quantity(i,1)* rate(i,1);
                elseif(strcmp(name(i),'CRGO steel')) %crgo steel is remaining
                    vlo(1,1)= vy(1,1)+vc(1,1)
                   quantity(i,1)= vlo(1,1).*7.65*10.^-6;
                     cost(i,1)= quantity(i,1)* rate(i,1);
                elseif(strcmp(name(i),'Copper Wire(Enamaled HV wire)'))
                    quantity(i,1)=vh*8.96*10.^-6;
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Copper Strip (paper coated)'))
                   quantity(i,1)= vl*8.96*10.^-6;
                   cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Buchholz Relay'))
                    quantity(i,1)=0
                    cost(i,1)=0
                elseif(strcmp(name(i),'Breather'))
                    quantity(i,1)=1;
                    if(Q<25000)
                        cost(i,1)= 1500;
                    else
                    cost(i,1)= 1500+7.36*abs(Q-25000)/1000;
                    end
                elseif(strcmp(name(i),'Casing'))
                    quantity(i,1)=1;
                    if(Q<25000)
                        cost(i,1)= 15000;
                    else
                    cost(i,1)= 15000+136.84*abs(Q-25000)/1000;
                    end
                elseif(strcmp(name(i),'HV - Bushing(3)'))
                    quantity(i,1)= 3;
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'LV- Bushing(4)'))
                    quantity(i,1)=4;
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'HV stud(3)'))
                     quantity(i,1)=3;
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Lv stud (4) for star'))
                     quantity(i,1)=4;
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'FIber Insulating Tube'))
                    quantity(i,1)=2;
                   cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Mills Tape'))
                quantity(i,1)=1;
                   cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Press Board'))
                    quantity(i,1)=3
                    cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Cotten Tape'))
                    quantity(i,1)=2;
                   cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Sleeves'))
                    quantity(i,1)=3;
                   cost(i,1)=rate(i,1)*quantity(i,1);
                elseif(strcmp(name(i),'Insulation Rising Cost'))
                    quantity(i,1)=0
                    if(Q<25000)
                        cost(i,1)= 3000;
                    else
                     cost(i,1)= 3000+14.73*abs(Q-25000)/1000;
                    end
                elseif(strcmp(name(i),'labor Cost'))
                     quantity(i,1)=0
                     if(Q<25000)
                        cost(i,1)= 10000;
                    else
                     cost(i,1)= 10000+84.21*abs(Q-25000)/1000;
                     end
                elseif(strcmp(name(i),'Electrical Energy Cost'))
                    cost(i,1)=rate(i,1);
                    quantity(i,1)=0
                    
                else      %'Miscellaneous'
                     quantity(i,1)=0
                     if(Q<25000)
                        cost(i,1)= 10000;
                    else
                     cost(i,1)= 10000+21.05*abs(Q-25000)/1000;
                     end
                end
            end
            kata=ones(20,3)
            total=0;
            for i= 1:+1:20
                kata(i,2)=rate(i,1);
                kata(i,1)=quantity(i,1);
                kata(i,3)=cost(i,1);
               total=total+cost(i,1)
            end
            kata(21,3)=total
            app.UITable.Enable='on'
            app.UITable2.Enable='on'
          
                app.UITable.Data= kata;


               %%  Uis=app.UITable2.Data
                %%%% Uip=app.UITable.Data
                %% dat=readtable('Save.xlsx')
                %% for i=1:+1:20
                  %%   for j=1:+1:3
                        %% pata(i,j)=Uis(i,j)
                   %  end
                %end
                 %for i=1:+1:21
                    %%for j=1:+1:3
                         %pata(i,j+3)=Uip(i,j)
                     %end
                 %end
            
           % writetable(pata, 'Save.xlsx');


        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            data= app.UITable.Data;
            %name= readtable('price.xlsx')
            for i= 1:+1:20
                
                for j= 1:+1:1
                    
                    rata(i,j)=data(i,j);
                    
                end
            end
            writetable(rata, 'price.xlsx');
            app.UITable.Enable='off'
            app.EditPriceButton.Text='Edit Price'
            app.SaveButton.Visible= 'off'
            app.GenerateEstiamtionButton.Visible='on'
        end

        % Button pushed function: LoadValueButton
        function LoadValueButtonPushed(app, event)
            data= readtable('price.xlsx');
            
            for i= 1:+1:20
                
                for j= 1:+1:1
                    
                    rata(i,j)=data(i,j);
                    
                end
            end
            app.UITable.Data= rata;
            
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if(strcmp(app.DropDown.Value, 'VA'))
               app.TransformerCapacityEditField.Limits=[1,999]
            else
                app.TransformerCapacityEditField.Limits=[1,500]
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INPUTPANALTab = uitab(app.TabGroup);
            app.INPUTPANALTab.Title = 'INPUT PANAL';
            app.TRANSFORMERDESIGNUSINGMATLABGUIPanel = uipanel(app.INPUTPANALTab);
            app.TRANSFORMERDESIGNUSINGMATLABGUIPanel.Title = 'TRANSFORMER DESIGN USING MATLAB & GUI';
            app.CalculateButton = uibutton(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Panel = uipanel(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'KVA', 'VA'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'KVA';
            app.DropDown_2 = uidropdown(app.Panel);
            app.DropDown_2.Items = {'V', 'KV', 'MV'};
            app.DropDown_2.Value = 'KV';
            app.DropDown_3 = uidropdown(app.Panel);
            app.DropDown_3.Items = {'V', 'KV', 'MV'};
            app.DropDown_3.Value = 'V';
            app.TransformerCapacityEditFieldLabel = uilabel(app.Panel);
            app.TransformerCapacityEditFieldLabel.Text = 'Transformer Capacity';
            app.TransformerCapacityEditField = uieditfield(app.Panel, 'numeric');
            app.TransformerCapacityEditField.Limits = [1 1000];
            app.TransformerCapacityEditField.Value = 1;
            app.PrimarywindingvoltageEditFieldLabel = uilabel(app.Panel);
            app.PrimarywindingvoltageEditFieldLabel.Text = 'Primary winding voltage';
            app.PrimarywindingvoltageEditField = uieditfield(app.Panel, 'numeric');
            app.PrimarywindingvoltageEditField.Limits = [1 999];
            app.PrimarywindingvoltageEditField.Value = 1;
            app.SecondarywindingvoltageEditFieldLabel = uilabel(app.Panel);
            app.SecondarywindingvoltageEditFieldLabel.Text = 'Secondary winding voltage';
            app.SecondarywindingvoltageEditField = uieditfield(app.Panel, 'numeric');
            app.SecondarywindingvoltageEditField.Limits = [1 999];
            app.SecondarywindingvoltageEditField.Value = 1;
            app.Panel_2 = uipanel(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.Amm2Label = uilabel(app.Panel_2);
            app.Amm2Label.Text = 'A/mm''2';
            app.degreeCelciusLabel = uilabel(app.Panel_2);
            app.degreeCelciusLabel.Text = 'degree Celcius';
            app.CurrentDensityEditFieldLabel = uilabel(app.Panel_2);
            app.CurrentDensityEditFieldLabel.Text = 'Current Density';
            app.CurrentDensityEditField = uieditfield(app.Panel_2, 'numeric');
            app.CurrentDensityEditField.Limits = [1 3];
            app.CurrentDensityEditField.Value = 2.2;
            app.MaxMagneticfluxdensityEditFieldLabel = uilabel(app.Panel_2);
            app.MaxMagneticfluxdensityEditFieldLabel.Text = 'Max. Magnetic flux density';
            app.MaxMagneticfluxdensityEditField = uieditfield(app.Panel_2, 'numeric');
            app.MaxMagneticfluxdensityEditField.Limits = [1 3];
            app.MaxMagneticfluxdensityEditField.ValueDisplayFormat = '%5.2f';
            app.MaxMagneticfluxdensityEditField.Value = 1.5;
            app.RatioofwindowheighttowindowwidthinDecimalformEditFieldLabel = uilabel(app.Panel_2);
            app.RatioofwindowheighttowindowwidthinDecimalformEditFieldLabel.Text = 'Ratio of window height to window width ( in Decimal form)';
            app.RatioofwindowheighttowindowwidthinDecimalformEditField = uieditfield(app.Panel_2, 'numeric');
            app.RatioofwindowheighttowindowwidthinDecimalformEditField.Limits = [2 4];
            app.RatioofwindowheighttowindowwidthinDecimalformEditField.Value = 2.5;
            app.SelectTheMaximumTemperatureEditFieldLabel = uilabel(app.Panel_2);
            app.SelectTheMaximumTemperatureEditFieldLabel.Text = 'Select The Maximum Temperature';
            app.SelectTheMaximumTemperatureEditField = uieditfield(app.Panel_2, 'numeric');
            app.SelectTheMaximumTemperatureEditField.Limits = [25 60];
            app.SelectTheMaximumTemperatureEditField.Value = 45;
            app.InsulationWidthEditFieldLabel = uilabel(app.Panel_2);
            app.InsulationWidthEditFieldLabel.Text = 'Insulation Width';
            app.InsulationWidthEditField = uieditfield(app.Panel_2, 'numeric');
            app.InsulationWidthEditField.Value = 0.5;
            app.mmLabel_16 = uilabel(app.Panel_2);
            app.mmLabel_16.Text = 'mm';
            app.TappingLabel = uilabel(app.Panel_2);
            app.TappingLabel.Text = 'Tapping (+-)';
            app.TappingEditField = uieditfield(app.Panel_2, 'numeric');
            app.TappingEditField.Value = 5;
            app.Label = uilabel(app.Panel_2);
            app.Label.Text = '%';
            app.pfEditFieldLabel = uilabel(app.Panel_2);
            app.pfEditFieldLabel.Text = 'pf';
            app.pfEditField = uieditfield(app.Panel_2, 'numeric');
            app.pfEditField.Limits = [0.1 1];
            app.pfEditField.Value = 0.8;
            app.Panel_3 = uipanel(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.TypeDropDownLabel = uilabel(app.Panel_3);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.Panel_3);
            app.TypeDropDown.Items = {'Single Phase Core type', 'Three phase core type( distribution)', 'Three phase core type( power)'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Three phase core type( distribution)';
            app.frequencyDropDownLabel = uilabel(app.Panel_3);
            app.frequencyDropDownLabel.Text = 'frequency';
            app.frequencyDropDown = uidropdown(app.Panel_3);
            app.frequencyDropDown.Items = {'50 Hz', '60 Hz'};
            app.frequencyDropDown.Value = '50 Hz';
            app.WindingtypeDropDown = uidropdown(app.Panel_3);
            app.WindingtypeDropDown.Items = {'Star-Star', 'Star- Delta', 'Delta-Star', 'Delta-Delta'};
            app.WindingtypeDropDown.Value = 'Star- Delta';
            app.CoreMaterialDropDownLabel = uilabel(app.Panel_3);
            app.CoreMaterialDropDownLabel.Text = 'Core Material';
            app.CoreMaterialDropDown = uidropdown(app.Panel_3);
            app.CoreMaterialDropDown.Items = {'Hot Rolled Steel', 'Cold Rolled Steel'};
            app.CoreMaterialDropDown.Value = 'Cold Rolled Steel';
            app.CoretypeDropDownLabel = uilabel(app.Panel_3);
            app.CoretypeDropDownLabel.Text = 'Core type';
            app.CoretypeDropDown = uidropdown(app.Panel_3);
            app.CoretypeDropDown.Items = {'Square', 'Cruciform', 'Three Stepped', 'Four Stepped'};
            app.CoretypeDropDown.Value = 'Cruciform';
            app.DesignPreferenceDropDownLabel = uilabel(app.Panel_3);
            app.DesignPreferenceDropDownLabel.Text = 'Design Preference';
            app.DesignPreferenceDropDown = uidropdown(app.Panel_3);
            app.DesignPreferenceDropDown.Items = {'Economical', 'Power effecient', 'Optimum', 'Normal'};
            app.DesignPreferenceDropDown.Value = 'Normal';
            app.TransformeroiltypeDropDownLabel = uilabel(app.Panel_3);
            app.TransformeroiltypeDropDownLabel.Text = 'Transformer oil type';
            app.TransformeroiltypeDropDown = uidropdown(app.Panel_3);
            app.TransformeroiltypeDropDown.Items = {'Naphtha Based', 'Paraphin Based'};
            app.TransformeroiltypeDropDown.Value = 'Naphtha Based';
            app.WindingTypeLabel = uilabel(app.Panel_3);
            app.WindingTypeLabel.Text = 'Winding Type';
            app.OutputConstantkEditFieldLabel = uilabel(app.Panel_3);
            app.OutputConstantkEditFieldLabel.Text = 'Output Constant (k)';
            app.OutputConstantkEditField = uieditfield(app.Panel_3, 'numeric');
            app.COMPANYNAMEEditFieldLabel = uilabel(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.COMPANYNAMEEditFieldLabel.Text = 'COMPANY NAME';
            app.COMPANYNAMEEditField = uieditfield(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel, 'text');
            app.COMPANYNAMEEditField.Value = 'TRANSFORMER';
            app.EditField = uieditfield(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel, 'numeric');
            app.Label_6 = uilabel(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.Label_6.Text = '';
            app.Lamp = uilamp(app.TRANSFORMERDESIGNUSINGMATLABGUIPanel);
            app.CoreandYokedesignTab = uitab(app.TabGroup);
            app.CoreandYokedesignTab.Title = 'Core and Yoke design';
            app.COREFRAMEDESIGNOUTPUTPanel = uipanel(app.CoreandYokedesignTab);
            app.COREFRAMEDESIGNOUTPUTPanel.Title = 'CORE & FRAME DESIGN OUTPUT';
            app.SizeoflaminationPanel = uipanel(app.COREFRAMEDESIGNOUTPUTPanel);
            app.SizeoflaminationPanel.Title = 'Size of lamination';
            app.SmallestWidthbEditFieldLabel = uilabel(app.SizeoflaminationPanel);
            app.SmallestWidthbEditFieldLabel.Text = 'Smallest Width (b)';
            app.SmallestWidthbEditField = uieditfield(app.SizeoflaminationPanel, 'numeric');
            app.SmallestWidthbEditField.Editable = 'off';
            app.ndLargestWidthEditFieldLabel = uilabel(app.SizeoflaminationPanel);
            app.ndLargestWidthEditFieldLabel.Text = '2nd Largest Width';
            app.ndLargestWidthEditField = uieditfield(app.SizeoflaminationPanel, 'numeric');
            app.ndLargestWidthEditField.Editable = 'off';
            app.rdLargestWidthEditFieldLabel = uilabel(app.SizeoflaminationPanel);
            app.rdLargestWidthEditFieldLabel.Text = '3rd Largest Width';
            app.rdLargestWidthEditField = uieditfield(app.SizeoflaminationPanel, 'numeric');
            app.rdLargestWidthEditField.Editable = 'off';
            app.mmLabel_11 = uilabel(app.SizeoflaminationPanel);
            app.mmLabel_11.Text = 'mm';
            app.mmLabel_12 = uilabel(app.SizeoflaminationPanel);
            app.mmLabel_12.Text = 'mm';
            app.mmLabel_13 = uilabel(app.SizeoflaminationPanel);
            app.mmLabel_13.Text = 'mm';
            app.mmLabel_14 = uilabel(app.SizeoflaminationPanel);
            app.mmLabel_14.Text = 'mm';
            app.LargestStampingWidthaEditFieldLabel = uilabel(app.SizeoflaminationPanel);
            app.LargestStampingWidthaEditFieldLabel.Text = 'Largest Stamping Width(a)';
            app.LargestStampingWidthaEditField = uieditfield(app.SizeoflaminationPanel, 'numeric');
            app.LargestStampingWidthaEditField.Editable = 'off';
            app.WindowdiscriptionPanel = uipanel(app.COREFRAMEDESIGNOUTPUTPanel);
            app.WindowdiscriptionPanel.Title = 'Window discription';
            app.WindowHeightEditFieldLabel = uilabel(app.WindowdiscriptionPanel);
            app.WindowHeightEditFieldLabel.Text = 'Window Height';
            app.WindowHeightEditField = uieditfield(app.WindowdiscriptionPanel, 'numeric');
            app.WindowHeightEditField.Editable = 'off';
            app.WindowWidthEditFieldLabel = uilabel(app.WindowdiscriptionPanel);
            app.WindowWidthEditFieldLabel.Text = 'Window Width';
            app.WindowWidthEditField = uieditfield(app.WindowdiscriptionPanel, 'numeric');
            app.WindowWidthEditField.Editable = 'off';
            app.mmLabel = uilabel(app.WindowdiscriptionPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.WindowdiscriptionPanel);
            app.mmLabel_2.Text = 'mm';
            app.AreaofWindowEditFieldLabel = uilabel(app.WindowdiscriptionPanel);
            app.AreaofWindowEditFieldLabel.Text = 'Area of Window';
            app.AreaofWindowEditField = uieditfield(app.WindowdiscriptionPanel, 'numeric');
            app.AreaofWindowEditField.Editable = 'off';
            app.mm2Label = uilabel(app.WindowdiscriptionPanel);
            app.mm2Label.Text = 'mm''2';
            app.DiameterofCircumsribingCircleEditFieldLabel = uilabel(app.WindowdiscriptionPanel);
            app.DiameterofCircumsribingCircleEditFieldLabel.Text = 'Diameter of Circumsribing Circle';
            app.DiameterofCircumsribingCircleEditField = uieditfield(app.WindowdiscriptionPanel, 'numeric');
            app.DiameterofCircumsribingCircleEditField.Editable = 'off';
            app.mmLabel_7 = uilabel(app.WindowdiscriptionPanel);
            app.mmLabel_7.Text = 'mm';
            app.FramedescriptionPanel = uipanel(app.COREFRAMEDESIGNOUTPUTPanel);
            app.FramedescriptionPanel.Title = 'Frame description';
            app.DepthofframeEditFieldLabel = uilabel(app.FramedescriptionPanel);
            app.DepthofframeEditFieldLabel.Text = 'Depth of frame';
            app.DepthofframeEditField = uieditfield(app.FramedescriptionPanel, 'numeric');
            app.DepthofframeEditField.Editable = 'off';
            app.HeightofFrameEditFieldLabel = uilabel(app.FramedescriptionPanel);
            app.HeightofFrameEditFieldLabel.Text = 'Height of Frame';
            app.HeightofFrameEditField = uieditfield(app.FramedescriptionPanel, 'numeric');
            app.HeightofFrameEditField.Editable = 'off';
            app.WidthofframeEditFieldLabel = uilabel(app.FramedescriptionPanel);
            app.WidthofframeEditFieldLabel.Text = 'Width of frame';
            app.WidthofframeEditField = uieditfield(app.FramedescriptionPanel, 'numeric');
            app.WidthofframeEditField.Editable = 'off';
            app.mmLabel_9 = uilabel(app.FramedescriptionPanel);
            app.mmLabel_9.Text = 'mm';
            app.mmLabel_6 = uilabel(app.FramedescriptionPanel);
            app.mmLabel_6.Text = 'mm';
            app.mmLabel_15 = uilabel(app.FramedescriptionPanel);
            app.mmLabel_15.Text = 'mm';
            app.DistancebetweenadjacentcoreEditFieldLabel = uilabel(app.FramedescriptionPanel);
            app.DistancebetweenadjacentcoreEditFieldLabel.Text = 'Distance between adjacent core';
            app.DistancebetweenadjacentcoreEditField = uieditfield(app.FramedescriptionPanel, 'numeric');
            app.YokeDiscriptionPanel = uipanel(app.COREFRAMEDESIGNOUTPUTPanel);
            app.YokeDiscriptionPanel.Title = 'Yoke Discription';
            app.HeightofyokeEditFieldLabel = uilabel(app.YokeDiscriptionPanel);
            app.HeightofyokeEditFieldLabel.Text = 'Height of yoke';
            app.HeightofyokeEditField = uieditfield(app.YokeDiscriptionPanel, 'numeric');
            app.HeightofyokeEditField.Editable = 'off';
            app.GrossareaofyokeEditFieldLabel = uilabel(app.YokeDiscriptionPanel);
            app.GrossareaofyokeEditFieldLabel.Text = 'Gross area of yoke';
            app.GrossareaofyokeEditField = uieditfield(app.YokeDiscriptionPanel, 'numeric');
            app.GrossareaofyokeEditField.Editable = 'off';
            app.DepthofyokeEditFieldLabel = uilabel(app.YokeDiscriptionPanel);
            app.DepthofyokeEditFieldLabel.Text = 'Depth of yoke';
            app.DepthofyokeEditField = uieditfield(app.YokeDiscriptionPanel, 'numeric');
            app.DepthofyokeEditField.Editable = 'off';
            app.mmLabel_3 = uilabel(app.YokeDiscriptionPanel);
            app.mmLabel_3.Text = 'mm';
            app.mmLabel_4 = uilabel(app.YokeDiscriptionPanel);
            app.mmLabel_4.Text = 'mm';
            app.mm2Label_2 = uilabel(app.YokeDiscriptionPanel);
            app.mm2Label_2.Text = 'mm''2';
            app.MaterialusedEditFieldLabel = uilabel(app.YokeDiscriptionPanel);
            app.MaterialusedEditFieldLabel.Text = 'Material used';
            app.MaterialusedEditField = uieditfield(app.YokeDiscriptionPanel, 'text');
            app.MaterialusedEditField.Editable = 'off';
            app.IronAreaEditFieldLabel = uilabel(app.YokeDiscriptionPanel);
            app.IronAreaEditFieldLabel.Text = 'Iron Area';
            app.IronAreaEditField = uieditfield(app.YokeDiscriptionPanel, 'numeric');
            app.mm2Label_5 = uilabel(app.YokeDiscriptionPanel);
            app.mm2Label_5.Text = 'mm''2';
            app.mmLabel_17 = uilabel(app.COREFRAMEDESIGNOUTPUTPanel);
            app.mmLabel_17.Text = 'mm';
            app.WindingDesignTab = uitab(app.TabGroup);
            app.WindingDesignTab.Title = 'Winding Design';
            app.WINDINGDESIGNPanel = uipanel(app.WindingDesignTab);
            app.WINDINGDESIGNPanel.Title = 'WINDING DESIGN';
            app.PrimaryWindingtypeEditFieldLabel = uilabel(app.WINDINGDESIGNPanel);
            app.PrimaryWindingtypeEditFieldLabel.Text = 'Primary Winding type';
            app.PrimaryWindingtypeEditField = uieditfield(app.WINDINGDESIGNPanel, 'text');
            app.PrimaryWindingtypeEditField.Editable = 'off';
            app.SecondaryWindingTypeEditFieldLabel = uilabel(app.WINDINGDESIGNPanel);
            app.SecondaryWindingTypeEditFieldLabel.Text = 'Secondary Winding Type';
            app.SecondaryWindingTypeEditField = uieditfield(app.WINDINGDESIGNPanel, 'text');
            app.SecondaryWindingTypeEditField.Editable = 'off';
            app.HVspecificationPanel = uipanel(app.WINDINGDESIGNPanel);
            app.HVspecificationPanel.Title = 'HV specification';
            app.RadialWidthEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.RadialWidthEditFieldLabel.Text = 'Radial Width';
            app.RadialWidthEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.RadialWidthEditField.Editable = 'off';
            app.NumberofTurnsEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.NumberofTurnsEditFieldLabel.Text = 'Number of Turns';
            app.NumberofTurnsEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.NumberofTurnsEditField.Editable = 'off';
            app.HVcurrentEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.HVcurrentEditFieldLabel.Text = 'HV current';
            app.HVcurrentEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.HVcurrentEditField.Editable = 'off';
            app.WindingEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.WindingEditFieldLabel.Text = 'Winding';
            app.WindingEditField = uieditfield(app.HVspecificationPanel, 'text');
            app.WindingEditField.Editable = 'off';
            app.AreaofconductorEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.AreaofconductorEditFieldLabel.Text = 'Area of conductor';
            app.AreaofconductorEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.AreaofconductorEditField.Editable = 'off';
            app.AxialHeightEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.AxialHeightEditFieldLabel.Text = 'Axial Height';
            app.AxialHeightEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.AxialHeightEditField.Editable = 'off';
            app.OutsidediameterEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.OutsidediameterEditFieldLabel.Text = 'Outside diameter';
            app.OutsidediameterEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.OutsidediameterEditField.Editable = 'off';
            app.WithTappingEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.WithTappingEditFieldLabel.Text = 'With Tapping';
            app.WithTappingEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.WithTappingEditField.Editable = 'off';
            app.NumberoflayerEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.NumberoflayerEditFieldLabel.Text = 'Number of layer';
            app.NumberoflayerEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.NumberoflayerEditField.Editable = 'off';
            app.TurnsLabel = uilabel(app.HVspecificationPanel);
            app.TurnsLabel.Text = 'Turns';
            app.TurnsLabel_2 = uilabel(app.HVspecificationPanel);
            app.TurnsLabel_2.Text = 'Turns';
            app.ALabel = uilabel(app.HVspecificationPanel);
            app.ALabel.Text = 'A';
            app.mm2Label_3 = uilabel(app.HVspecificationPanel);
            app.mm2Label_3.Text = 'mm^2';
            app.mmLabel_18 = uilabel(app.HVspecificationPanel);
            app.mmLabel_18.Text = 'mm';
            app.mmLabel_19 = uilabel(app.HVspecificationPanel);
            app.mmLabel_19.Text = 'mm';
            app.mmLabel_20 = uilabel(app.HVspecificationPanel);
            app.mmLabel_20.Text = 'mm';
            app.mmLabel_21 = uilabel(app.HVspecificationPanel);
            app.mmLabel_21.Text = 'mm';
            app.Amm2Label_3 = uilabel(app.HVspecificationPanel);
            app.Amm2Label_3.Text = 'A/mm2';
            app.currentDensityEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.currentDensityEditFieldLabel.Text = 'current Density';
            app.currentDensityEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.currentDensityEditField.Editable = 'off';
            app.NumberofCoilsEditFieldLabel = uilabel(app.HVspecificationPanel);
            app.NumberofCoilsEditFieldLabel.Text = 'Number of Coils';
            app.NumberofCoilsEditField = uieditfield(app.HVspecificationPanel, 'numeric');
            app.NumberofCoilsEditField.Editable = 'off';
            app.mmLabel_31 = uilabel(app.HVspecificationPanel);
            app.mmLabel_31.Text = 'mm';
            app.NumberofParallelConductorEditField_2Label = uilabel(app.HVspecificationPanel);
            app.NumberofParallelConductorEditField_2Label.Text = 'Number of Parallel Conductor';
            app.NumberofParallelConductorEditField_2 = uieditfield(app.HVspecificationPanel, 'numeric');
            app.NumberofParallelConductorEditField_2.Editable = 'off';
            app.LVspecificationPanel = uipanel(app.WINDINGDESIGNPanel);
            app.LVspecificationPanel.Title = 'LV specification';
            app.NumberofturnsEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.NumberofturnsEditFieldLabel.Text = 'Number of turns';
            app.NumberofturnsEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.NumberofturnsEditField.Editable = 'off';
            app.LVCurrentEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.LVCurrentEditFieldLabel.Text = 'LV Current';
            app.LVCurrentEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.LVCurrentEditField.Editable = 'off';
            app.AreaofConductorEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.AreaofConductorEditFieldLabel.Text = 'Area of Conductor';
            app.AreaofConductorEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.AreaofConductorEditField.Editable = 'off';
            app.AxialheightEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.AxialheightEditFieldLabel.Text = 'Axial height';
            app.AxialheightEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.AxialheightEditField.Editable = 'off';
            app.RadialwidthEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.RadialwidthEditFieldLabel.Text = 'Radial width';
            app.RadialwidthEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.RadialwidthEditField.Editable = 'off';
            app.OutsideDiameterEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.OutsideDiameterEditFieldLabel.Text = 'Outside Diameter';
            app.OutsideDiameterEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.OutsideDiameterEditField.Editable = 'off';
            app.InsidediameterEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.InsidediameterEditFieldLabel.Text = 'Inside diameter';
            app.InsidediameterEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.InsidediameterEditField.Editable = 'off';
            app.WindingEditField_2Label = uilabel(app.LVspecificationPanel);
            app.WindingEditField_2Label.Text = 'Winding';
            app.WindingEditField_2 = uieditfield(app.LVspecificationPanel, 'text');
            app.WindingEditField_2.Editable = 'off';
            app.mmLabel_22 = uilabel(app.LVspecificationPanel);
            app.mmLabel_22.Text = 'mm';
            app.mmLabel_23 = uilabel(app.LVspecificationPanel);
            app.mmLabel_23.Text = 'mm';
            app.mmLabel_24 = uilabel(app.LVspecificationPanel);
            app.mmLabel_24.Text = 'mm';
            app.mmLabel_25 = uilabel(app.LVspecificationPanel);
            app.mmLabel_25.Text = 'mm';
            app.mm2Label_4 = uilabel(app.LVspecificationPanel);
            app.mm2Label_4.Text = 'mm^2';
            app.ALabel_2 = uilabel(app.LVspecificationPanel);
            app.ALabel_2.Text = 'A';
            app.NumberofParallelConductorEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.NumberofParallelConductorEditFieldLabel.Text = 'Number of Parallel Conductor';
            app.NumberofParallelConductorEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.NumberofParallelConductorEditField.Editable = 'off';
            app.TurnsLabel_3 = uilabel(app.LVspecificationPanel);
            app.TurnsLabel_3.Text = 'Turns';
            app.ThicknessofconductorEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.ThicknessofconductorEditFieldLabel.Text = 'Thickness of conductor';
            app.ThicknessofconductorEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.ThicknessofconductorEditField.Editable = 'off';
            app.WidthofconductorEditFieldLabel = uilabel(app.LVspecificationPanel);
            app.WidthofconductorEditFieldLabel.Text = 'Width of conductor';
            app.WidthofconductorEditField = uieditfield(app.LVspecificationPanel, 'numeric');
            app.WidthofconductorEditField.Editable = 'off';
            app.mmLabel_26 = uilabel(app.LVspecificationPanel);
            app.mmLabel_26.Text = 'mm';
            app.mmLabel_27 = uilabel(app.LVspecificationPanel);
            app.mmLabel_27.Text = 'mm';
            app.CurrentDensityEditField_2Label = uilabel(app.LVspecificationPanel);
            app.CurrentDensityEditField_2Label.Text = 'Current Density';
            app.CurrentDensityEditField_2 = uieditfield(app.LVspecificationPanel, 'numeric');
            app.CurrentDensityEditField_2.Editable = 'off';
            app.Amm2Label_2 = uilabel(app.LVspecificationPanel);
            app.Amm2Label_2.Text = 'A/mm2';
            app.InsideDiameterEditFieldLabel = uilabel(app.WINDINGDESIGNPanel);
            app.InsideDiameterEditFieldLabel.Text = 'Inside Diameter';
            app.InsideDiameterEditField = uieditfield(app.WINDINGDESIGNPanel, 'numeric');
            app.InsideDiameterEditField.Editable = 'off';
            app.RegulationTab = uitab(app.TabGroup);
            app.RegulationTab.Title = 'Regulation';
            app.ResistanceReactanceRegulationPanel = uipanel(app.RegulationTab);
            app.ResistanceReactanceRegulationPanel.Title = 'Resistance, Reactance, Regulation';
            app.ResistanceEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.ResistanceEditFieldLabel.Text = 'Resistance';
            app.ResistanceEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.ResistanceEditField.Editable = 'off';
            app.ResistanceinpuEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.ResistanceinpuEditFieldLabel.Text = 'Resistance in pu';
            app.ResistanceinpuEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.ResistanceinpuEditField.Editable = 'off';
            app.ReactanceEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.ReactanceEditFieldLabel.Text = 'Reactance';
            app.ReactanceEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.ReactanceEditField.Editable = 'off';
            app.ReactanceinpuEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.ReactanceinpuEditFieldLabel.Text = 'Reactance in pu';
            app.ReactanceinpuEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.ReactanceinpuEditField.Editable = 'off';
            app.ImpedanceinpuEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.ImpedanceinpuEditFieldLabel.Text = 'Impedance in pu';
            app.ImpedanceinpuEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.ImpedanceinpuEditField.Editable = 'off';
            app.RegulationEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.RegulationEditFieldLabel.Text = 'Regulation';
            app.RegulationEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'numeric');
            app.RegulationEditField.Editable = 'off';
            app.Label_4 = uilabel(app.ResistanceReactanceRegulationPanel);
            app.Label_4.Text = 'Ω';
            app.Label_5 = uilabel(app.ResistanceReactanceRegulationPanel);
            app.Label_5.Text = 'Ω';
            app.InsulationTypeEditFieldLabel = uilabel(app.ResistanceReactanceRegulationPanel);
            app.InsulationTypeEditFieldLabel.Text = 'Insulation Type';
            app.InsulationTypeEditField = uieditfield(app.ResistanceReactanceRegulationPanel, 'text');
            app.LossefficiencyTab = uitab(app.TabGroup);
            app.LossefficiencyTab.Title = 'Loss & efficiency';
            app.LossEfficiencyPanel = uipanel(app.LossefficiencyTab);
            app.LossEfficiencyPanel.Title = 'Loss & Efficiency';
            app.LossesPanel = uipanel(app.LossEfficiencyPanel);
            app.LossesPanel.Title = 'Losses';
            app.CopperLossEditFieldLabel = uilabel(app.LossesPanel);
            app.CopperLossEditFieldLabel.Text = 'Copper Loss';
            app.CopperLossEditField = uieditfield(app.LossesPanel, 'numeric');
            app.CopperLossEditField.Editable = 'off';
            app.StraylosswithCulossEditFieldLabel = uilabel(app.LossesPanel);
            app.StraylosswithCulossEditFieldLabel.Text = 'Stray loss with Cu loss';
            app.StraylosswithCulossEditField = uieditfield(app.LossesPanel, 'numeric');
            app.StraylosswithCulossEditField.Editable = 'off';
            app.LossacrossLimbEditFieldLabel = uilabel(app.LossesPanel);
            app.LossacrossLimbEditFieldLabel.Text = 'Loss across Limb';
            app.LossacrossLimbEditField = uieditfield(app.LossesPanel, 'numeric');
            app.LossacrossLimbEditField.Editable = 'off';
            app.LossacrossYokeEditFieldLabel = uilabel(app.LossesPanel);
            app.LossacrossYokeEditFieldLabel.Text = 'Loss across Yoke';
            app.LossacrossYokeEditField = uieditfield(app.LossesPanel, 'numeric');
            app.LossacrossYokeEditField.Editable = 'off';
            app.TotalCoreLossEditFieldLabel = uilabel(app.LossesPanel);
            app.TotalCoreLossEditFieldLabel.Text = 'Total Core Loss';
            app.TotalCoreLossEditField = uieditfield(app.LossesPanel, 'numeric');
            app.TotalCoreLossEditField.Editable = 'off';
            app.TotalLossEditFieldLabel = uilabel(app.LossesPanel);
            app.TotalLossEditFieldLabel.Text = 'Total Loss';
            app.TotalLossEditField = uieditfield(app.LossesPanel, 'numeric');
            app.TotalLossEditField.Editable = 'off';
            app.WattLabel = uilabel(app.LossesPanel);
            app.WattLabel.Text = 'Watt';
            app.WattLabel_3 = uilabel(app.LossesPanel);
            app.WattLabel_3.Text = 'Watt';
            app.WattLabel_4 = uilabel(app.LossesPanel);
            app.WattLabel_4.Text = 'Watt';
            app.WattLabel_5 = uilabel(app.LossesPanel);
            app.WattLabel_5.Text = 'Watt';
            app.WattLabel_6 = uilabel(app.LossesPanel);
            app.WattLabel_6.Text = 'Watt';
            app.EfficiencyPanel = uipanel(app.LossEfficiencyPanel);
            app.EfficiencyPanel.Title = 'Efficiency';
            app.FullLoadEfficiencyEditFieldLabel = uilabel(app.EfficiencyPanel);
            app.FullLoadEfficiencyEditFieldLabel.Text = 'Full Load Efficiency';
            app.FullLoadEfficiencyEditField = uieditfield(app.EfficiencyPanel, 'numeric');
            app.FullLoadEfficiencyEditField.Editable = 'off';
            app.HalfLoadEfficiencyEditFieldLabel = uilabel(app.EfficiencyPanel);
            app.HalfLoadEfficiencyEditFieldLabel.Text = 'Half Load Efficiency';
            app.HalfLoadEfficiencyEditField = uieditfield(app.EfficiencyPanel, 'numeric');
            app.HalfLoadEfficiencyEditField.Editable = 'off';
            app.MaximumEfficiencyoccuratEditFieldLabel = uilabel(app.EfficiencyPanel);
            app.MaximumEfficiencyoccuratEditFieldLabel.Text = 'Maximum Efficiency occur at';
            app.MaximumEfficiencyoccuratEditField = uieditfield(app.EfficiencyPanel, 'numeric');
            app.MaximumEfficiencyoccuratEditField.Editable = 'off';
            app.offullloadLabel = uilabel(app.EfficiencyPanel);
            app.offullloadLabel.Text = '% of full load';
            app.Label_2 = uilabel(app.EfficiencyPanel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.EfficiencyPanel);
            app.Label_3.Text = '%';
            app.WattLabel_2 = uilabel(app.LossEfficiencyPanel);
            app.WattLabel_2.Text = 'Watt';
            app.TankDesignTab = uitab(app.TabGroup);
            app.TankDesignTab.Title = 'Tank Design';
            app.TankDesignPanel = uipanel(app.TankDesignTab);
            app.TankDesignPanel.Title = 'Tank Design';
            app.HeightoftankEditFieldLabel = uilabel(app.TankDesignPanel);
            app.HeightoftankEditFieldLabel.Text = 'Height of tank';
            app.HeightoftankEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.HeightoftankEditField.Editable = 'off';
            app.WidthofTankEditFieldLabel = uilabel(app.TankDesignPanel);
            app.WidthofTankEditFieldLabel.Text = 'Width of Tank';
            app.WidthofTankEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.WidthofTankEditField.Editable = 'off';
            app.LengthofTankEditFieldLabel = uilabel(app.TankDesignPanel);
            app.LengthofTankEditFieldLabel.Text = 'Length of Tank';
            app.LengthofTankEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.LengthofTankEditField.Editable = 'off';
            app.TemperatureRiseEditFieldLabel = uilabel(app.TankDesignPanel);
            app.TemperatureRiseEditFieldLabel.Text = 'Temperature Rise ';
            app.TemperatureRiseEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.TemperatureRiseEditField.Editable = 'off';
            app.WallareaofeachtubeEditFieldLabel = uilabel(app.TankDesignPanel);
            app.WallareaofeachtubeEditFieldLabel.Text = 'Wall area of each tube';
            app.WallareaofeachtubeEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.WallareaofeachtubeEditField.Editable = 'off';
            app.NumberoftubesEditFieldLabel = uilabel(app.TankDesignPanel);
            app.NumberoftubesEditFieldLabel.Text = 'Number of tubes';
            app.NumberoftubesEditField = uieditfield(app.TankDesignPanel, 'numeric');
            app.NumberoftubesEditField.Editable = 'off';
            app.mmLabel_28 = uilabel(app.TankDesignPanel);
            app.mmLabel_28.Text = 'mm';
            app.mmLabel_29 = uilabel(app.TankDesignPanel);
            app.mmLabel_29.Text = 'mm';
            app.mmLabel_30 = uilabel(app.TankDesignPanel);
            app.mmLabel_30.Text = 'mm';
            app.degreeCelciusLabel_2 = uilabel(app.TankDesignPanel);
            app.degreeCelciusLabel_2.Text = 'degree Celcius';
            app.mm2Label_6 = uilabel(app.TankDesignPanel);
            app.mm2Label_6.Text = 'mm''2';
            app.CoolingTypeEditFieldLabel = uilabel(app.TankDesignPanel);
            app.CoolingTypeEditFieldLabel.Text = 'Cooling Type';
            app.CoolingTypeEditField = uieditfield(app.TankDesignPanel, 'text');
            app.ExpensesTab = uitab(app.TabGroup);
            app.ExpensesTab.Title = 'Expenses';
            app.ExpenditurePanel = uipanel(app.ExpensesTab);
            app.ExpenditurePanel.Title = 'Expenditure ';
            app.UITable = uitable(app.ExpenditurePanel);
            app.UITable.ColumnName = {'Quantity'; 'Rate'; 'Total'; 'Remarks'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true true];
            app.EditPriceButton = uibutton(app.ExpenditurePanel, 'push');
            app.EditPriceButton.ButtonPushedFcn = createCallbackFcn(app, @EditPriceButtonPushed, true);
            app.EditPriceButton.Text = 'Edit Price';
            app.SaveButton = uibutton(app.ExpenditurePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.GenerateEstiamtionButton = uibutton(app.ExpenditurePanel, 'push');
            app.GenerateEstiamtionButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateEstiamtionButtonPushed, true);
            app.GenerateEstiamtionButton.Text = 'Generate Estiamtion';
            app.LoadValueButton = uibutton(app.ExpenditurePanel, 'push');
            app.LoadValueButton.ButtonPushedFcn = createCallbackFcn(app, @LoadValueButtonPushed, true);
            app.LoadValueButton.Text = 'Load Value';
            app.UITable2 = uitable(app.ExpenditurePanel);
            app.UITable2.ColumnName = {'S.N'; 'Items'; 'Unit'};
            app.UITable2.RowName = {};
            app.ReportGeneratorTab = uitab(app.TabGroup);
            app.ReportGeneratorTab.Title = 'Report Generator';
            app.ReportGeneratorPanel = uipanel(app.ReportGeneratorTab);
            app.ReportGeneratorPanel.Title = 'Report Generator';
            app.ReportFormatDropDownLabel = uilabel(app.ReportGeneratorPanel);
            app.ReportFormatDropDownLabel.Text = 'Report Format';
            app.ReportFormatDropDown = uidropdown(app.ReportGeneratorPanel);
            app.ReportFormatDropDown.Items = {' docx (word file)'};
            app.ReportFormatDropDown.Value = ' docx (word file)';
            app.GenerateReportButton = uibutton(app.ReportGeneratorPanel, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            app.EditField_2 = uieditfield(app.ReportGeneratorPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Transformer_design

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end